classdef Entregable2_Equipo1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CorrienteInducidamaxEditField  matlab.ui.control.NumericEditField
        CorrienteInducidamaxEditFieldLabel  matlab.ui.control.Label
        FuerzamaxEditField             matlab.ui.control.NumericEditField
        FuerzamaxEditFieldLabel        matlab.ui.control.Label
        MomentoInducidomaxEditField    matlab.ui.control.NumericEditField
        MomentoInducidomaxEditFieldLabel  matlab.ui.control.Label
        ResistenciaCobre               matlab.ui.control.Spinner
        ResLabel                       matlab.ui.control.Label
        cbAceleracion                  matlab.ui.control.CheckBox
        cbJerk                         matlab.ui.control.CheckBox
        cbPosicion                     matlab.ui.control.CheckBox
        cbVelocidad                    matlab.ui.control.CheckBox
        GraficarButton                 matlab.ui.control.Button
        mIman                          matlab.ui.control.Spinner
        mImanSpinnerLabel              matlab.ui.control.Label
        RadioIman                      matlab.ui.control.Spinner
        RImanSpinnerLabel              matlab.ui.control.Label
        AreaAnillo                     matlab.ui.control.Spinner
        AAnilloSpinnerLabel            matlab.ui.control.Label
        Momento                        matlab.ui.control.Slider
        MomentoSliderLabel             matlab.ui.control.Label
        axAceleracion                  matlab.ui.control.UIAxes
        axJerk                         matlab.ui.control.UIAxes
        axPosicion                     matlab.ui.control.UIAxes
        axVelocidad                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Momento
        function MomentoValueChanged(app, event)
            momento = app.Momento.Value;
            
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            cla(app.axAceleracion);
            cla(app.axVelocidad);
            cla(app.axJerk);
            cla(app.axPosicion);
            
            cbV = app.cbVelocidad.Value;
            cbP = app.cbPosicion.Value;
            cbA = app.cbAceleracion.Value;
            cbJ = app.cbJerk.Value;

            
            momento = app.Momento.Value;
            a_ring = app.AreaAnillo.Value;
            a = app.RadioIman.Value;
            m = app.mIman.Value;
            R = app.ResistenciaCobre.Value;

            mu0 = 4*pi*10e-7;
            dt = 0.01; %Paso
            g = -9.81; %Gravedad
            A = pi*a^2; %área del imán
            N = 2;  %Número de espiras del solenoide

            n = 100; %Iteraciones

            z = zeros(1,n); %Altura SLIDER
            
            B = zeros(1,n); %Campo del imán cayendo
            
            flujo = zeros(1,n); %Flujo del imán
            
            fem = zeros(1,n); %Fem inducida
            
            I_inducida = zeros(1,n); %Corriente inducida
            
            B_inducida = zeros(1,n); %Campo inducido por la corriente
            
            Fz = zeros(1,n); %Fuerza inducida
            
            acel = zeros(1,n); %Aceleración
            
            jerk = zeros(1,n); %Diferencial de aceleración
            
            vz = zeros(1,n); %Velocidad de caía
            
            t = zeros(1,n); %Tiempo SLIDER (tal vez)
            
            zz = zeros(1,n); %Caída libre normal
            
            vzz = zeros(1,n); %caída libre normal
            
            momento_inducido = zeros(1,n); %Momento Magnético inducido
            
            for i = 1:n
                if i == 1
                    z(1) = 1; %Altura inicial
                    B(1) = mu0*momento/( 2*pi*(a^2 + z(1)^2)^(3/2) ); %Campo inicial
                    flujo(1) = B(1)*A; %Flujo inicial
                    fem(1) = 0;
                    I_inducida(1) = 0;
                    B_inducida(1) = 0;
                    Fz(1) = 0;
                    acel(1) = g; %Al principio es de la gravedad
                    vz(1) = 0;
                    jerk = 0;
                    t(1) = 0;
                    zz(1) = z(1); %Borrable
                    vzz(1) =0; %Borrables
                    momento_inducido(1) = 0;
                else
                    B(i) = mu0*momento/( 2*pi*(a^2 + z(i-1)^2)^(3/2) );
                    flujo(i) = B(i)*A;
                    fem(i) = N*(flujo(i)-flujo(i-1))/dt;
                    I_inducida(i) = fem(i)/R;
                    B_inducida(i) = mu0*I_inducida(i)*a_ring^2/( 2*(a_ring^2 + z(i-1)^2)^(3/2) );
                    momento_inducido(i) = I_inducida(i)*a_ring^2*pi; %Mostrar
                    Fz(i) = (mu0/4*pi)*momento*momento_inducido(i)/z(i-1)^2; %Mostrar
                    acel(i) = (Fz(i) + m*g)/m; 
                    jerk(i) = (acel(i) - acel(i-1))/dt;
                    vz(i) = vz(i-1)+acel(i)*dt;
                    z(i) = z(i-1) + (vz(i)*dt) + 0.5*acel(i)*dt^2;
                    t(i) = t(i-1) + dt;
                    vzz(i) = vzz(i-1)+g*dt;
                    zz(i) = zz(i-1)+vzz(i)*dt + 0.5*g*dt^2;

                    
                end
            end

            app.MomentoInducidomaxEditField.Value = max(momento_inducido);
            app.FuerzamaxEditField.Value = max(Fz);
            app.CorrienteInducidamaxEditField.Value = max(I_inducida);

            if cbJ == 1
                plot(app.axJerk,t,jerk)
                title(app.axJerk,"T vs Jerk")
                grid on;
                xline(0); yline(0);
                xlabel(app.axJerk,"Tiempo (s)"); ylabel(app.axJerk,"Jerk (m/s^3)");
            end
            
            if cbP== 1
                plot(app.axPosicion,t,z)
                hold on
                %plot(app.axPosicion,t,zz)
                title(app.axPosicion,"T vs Posición")
                grid on;
                xline(0); yline(0);
                xlabel(app.axPosicion,"Tiempo (s)"); ylabel(app.axPosicion,"Posición (m)");
                hold off;
                %legend(app.axPosicion,"Frenado","Caída Libre");
            end

            if cbV == 1
                plot(app.axVelocidad,t,vz)
                title(app.axVelocidad,"T vs Velocidad")
                hold on
                %plot(app.axVelocidad,t,vzz)
                grid on;
                xline(0); yline(0);
                xlabel(app.axVelocidad,"Tiempo (s)"); ylabel(app.axVelocidad,"Velocidad (m/s)");
                hold off
                %legend(app.axVelocidad,"Frenado","Caída Libre");
            end

            if cbA == 1
                plot(app.axAceleracion,t,acel)
                title(app.axAceleracion, "T vs Aceleración")
                grid on;
                xline(0); yline(0);
                xlabel(app.axAceleracion, "Tiempo (s)"); ylabel(app.axAceleracion, "Aceleración (m/s^2)");
            end

        end

        % Value changed function: AreaAnillo
        function AreaAnilloValueChanged(app, event)
            aAnillo = app.AreaAnillo.Value;
            
        end

        % Value changed function: RadioIman
        function RadioImanValueChanged(app, event)
            riman = app.RadioIman.Value;
            
        end

        % Value changed function: ResistenciaCobre
        function ResistenciaCobreValueChanged(app, event)
            rcobre = app.ResistenciaCobre.Value;
            
        end

        % Value changed function: mIman
        function mImanValueChanged(app, event)
            miman = app.mIman.Value;
            
        end

        % Value changed function: cbVelocidad
        function cbVelocidadValueChanged(app, event)
            cbvelocidad = app.cbVelocidad.Value;
            
        end

        % Value changed function: cbPosicion
        function cbPosicionValueChanged(app, event)
            cbposicion = app.cbPosicion.Value;
            
        end

        % Value changed function: cbJerk
        function cbJerkValueChanged(app, event)
            cbjerk = app.cbJerk.Value;
            
        end

        % Value changed function: cbAceleracion
        function cbAceleracionValueChanged(app, event)
            cbaceleracion = app.cbAceleracion.Value;
            
        end

        % Value changed function: MomentoInducidomaxEditField
        function MomentoInducidomaxEditFieldValueChanged(app, event)
            momentoInducido = app.MomentoInducidomaxEditField.Value;
            
        end

        % Value changed function: FuerzamaxEditField
        function FuerzamaxEditFieldValueChanged(app, event)
            fuerza = app.FuerzamaxEditField.Value;
            
        end

        % Value changed function: CorrienteInducidamaxEditField
        function CorrienteInducidamaxEditFieldValueChanged(app, event)
            corrienteInducida = app.CorrienteInducidamaxEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axVelocidad = uiaxes(app.UIFigure);
            title(app.axVelocidad, 'Title')
            xlabel(app.axVelocidad, 'X')
            ylabel(app.axVelocidad, 'Y')
            zlabel(app.axVelocidad, 'Z')
            app.axPosicion = uiaxes(app.UIFigure);
            title(app.axPosicion, 'Title')
            xlabel(app.axPosicion, 'X')
            ylabel(app.axPosicion, 'Y')
            zlabel(app.axPosicion, 'Z')
            app.axJerk = uiaxes(app.UIFigure);
            title(app.axJerk, 'Title')
            xlabel(app.axJerk, 'X')
            ylabel(app.axJerk, 'Y')
            zlabel(app.axJerk, 'Z')
            app.axAceleracion = uiaxes(app.UIFigure);
            title(app.axAceleracion, 'Title')
            xlabel(app.axAceleracion, 'X')
            ylabel(app.axAceleracion, 'Y')
            zlabel(app.axAceleracion, 'Z')
            app.MomentoSliderLabel = uilabel(app.UIFigure);
            app.MomentoSliderLabel.Text = 'Momento';
            app.Momento = uislider(app.UIFigure);
            app.Momento.Limits = [1800 3000];
            app.Momento.ValueChangedFcn = createCallbackFcn(app, @MomentoValueChanged, true);
            app.Momento.Value = 2000;
            app.AAnilloSpinnerLabel = uilabel(app.UIFigure);
            app.AAnilloSpinnerLabel.Text = 'A. Anillo';
            app.AreaAnillo = uispinner(app.UIFigure);
            app.AreaAnillo.Step = 0.1;
            app.AreaAnillo.Limits = [0.1 1];
            app.AreaAnillo.ValueChangedFcn = createCallbackFcn(app, @AreaAnilloValueChanged, true);
            app.AreaAnillo.Value = 0.1;
            app.RImanSpinnerLabel = uilabel(app.UIFigure);
            app.RImanSpinnerLabel.Text = 'R. Iman';
            app.RadioIman = uispinner(app.UIFigure);
            app.RadioIman.Step = 0.1;
            app.RadioIman.Limits = [0.02 1];
            app.RadioIman.ValueChangedFcn = createCallbackFcn(app, @RadioImanValueChanged, true);
            app.RadioIman.Value = 0.03;
            app.mImanSpinnerLabel = uilabel(app.UIFigure);
            app.mImanSpinnerLabel.Text = 'm. Iman';
            app.mIman = uispinner(app.UIFigure);
            app.mIman.Step = 0.01;
            app.mIman.Limits = [0.02 0.1];
            app.mIman.ValueChangedFcn = createCallbackFcn(app, @mImanValueChanged, true);
            app.mIman.Value = 0.03;
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.cbVelocidad = uicheckbox(app.UIFigure);
            app.cbVelocidad.ValueChangedFcn = createCallbackFcn(app, @cbVelocidadValueChanged, true);
            app.cbVelocidad.Text = '';
            app.cbVelocidad.Value = true;
            app.cbPosicion = uicheckbox(app.UIFigure);
            app.cbPosicion.ValueChangedFcn = createCallbackFcn(app, @cbPosicionValueChanged, true);
            app.cbPosicion.Text = '';
            app.cbPosicion.Value = true;
            app.cbJerk = uicheckbox(app.UIFigure);
            app.cbJerk.ValueChangedFcn = createCallbackFcn(app, @cbJerkValueChanged, true);
            app.cbJerk.Text = '';
            app.cbJerk.Value = true;
            app.cbAceleracion = uicheckbox(app.UIFigure);
            app.cbAceleracion.ValueChangedFcn = createCallbackFcn(app, @cbAceleracionValueChanged, true);
            app.cbAceleracion.Text = '';
            app.cbAceleracion.Value = true;
            app.ResLabel = uilabel(app.UIFigure);
            app.ResLabel.Text = 'Res.';
            app.ResistenciaCobre = uispinner(app.UIFigure);
            app.ResistenciaCobre.Step = 0.1;
            app.ResistenciaCobre.Limits = [0.1 1];
            app.ResistenciaCobre.ValueChangedFcn = createCallbackFcn(app, @ResistenciaCobreValueChanged, true);
            app.ResistenciaCobre.Value = 1;
            app.MomentoInducidomaxEditFieldLabel = uilabel(app.UIFigure);
            app.MomentoInducidomaxEditFieldLabel.Text = 'Momento Inducido (max)';
            app.MomentoInducidomaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MomentoInducidomaxEditField.ValueChangedFcn = createCallbackFcn(app, @MomentoInducidomaxEditFieldValueChanged, true);
            app.FuerzamaxEditFieldLabel = uilabel(app.UIFigure);
            app.FuerzamaxEditFieldLabel.Text = 'Fuerza (max)';
            app.FuerzamaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.FuerzamaxEditField.ValueChangedFcn = createCallbackFcn(app, @FuerzamaxEditFieldValueChanged, true);
            app.CorrienteInducidamaxEditFieldLabel = uilabel(app.UIFigure);
            app.CorrienteInducidamaxEditFieldLabel.Text = 'Corriente Inducida (max)';
            app.CorrienteInducidamaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.CorrienteInducidamaxEditField.ValueChangedFcn = createCallbackFcn(app, @CorrienteInducidamaxEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Entregable2_Equipo1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end